classdef Suspension < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        WheelbasemmEditFieldLabel    matlab.ui.control.Label
        WheelbasemmEditField         matlab.ui.control.NumericEditField
        TrackmmEditFieldLabel        matlab.ui.control.Label
        TrackmmEditField             matlab.ui.control.NumericEditField
        CamberEditFieldLabel         matlab.ui.control.Label
        CamberEditField              matlab.ui.control.NumericEditField
        wheelLoadRearEditFieldLabel  matlab.ui.control.Label
        wheelLoadRearEditField       matlab.ui.control.NumericEditField
        TyreParametersPanel          matlab.ui.container.Panel
        J_TirekgmEditFieldLabel      matlab.ui.control.Label
        J_TirekgmEditField           matlab.ui.control.NumericEditField
        p_tirePaEditFieldLabel       matlab.ui.control.Label
        p_tirePaEditField            matlab.ui.control.NumericEditField
        LMUXSliderLabel              matlab.ui.control.Label
        LMUXSlider                   matlab.ui.control.Slider
        LMUYSliderLabel              matlab.ui.control.Label
        LMUYSlider                   matlab.ui.control.Slider
        RollingResistanceEditFieldLabel  matlab.ui.control.Label
        RollingResistanceEditField   matlab.ui.control.NumericEditField
        SaveButton                   matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        BrakesPanel                  matlab.ui.container.Panel
        BrakeForceNEditFieldLabel    matlab.ui.control.Label
        BrakeForceNEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = private)
        
        function saveData(app)
            app.CallingApp.wheelbase = app.WheelbasemmEditField.Value;
            app.CallingApp.track = app.TrackmmEditField.Value;
            app.CallingApp.J_Tire = app.J_TirekgmEditField.Value;
            app.CallingApp.p_Tire = app.p_tirePaEditField.Value;
            app.CallingApp.LMUX = app.LMUXSlider.Value;
            app.CallingApp.LMUY = app.LMUYSlider.Value;
            app.CallingApp.k_R = app.RollingResistanceEditField.Value;
            app.CallingApp.FB = app.BrakeForceNEditField.Value;
            app.CallingApp.camber = app.CamberEditField.Value;
            app.CallingApp.m_ph = app.wheelLoadRearEditField.Value;
        end
        
        function loadApp(app)
            % Loads data from main app
            app.WheelbasemmEditField.Value = app.CallingApp.wheelbase;
            app.TrackmmEditField.Value = app.CallingApp.track;
            app.J_TirekgmEditField.Value = app.CallingApp.J_Tire;
            app.p_tirePaEditField.Value = app.CallingApp.p_Tire;
            app.LMUXSlider.Value = app.CallingApp.LMUX;
            app.LMUYSlider.Value = app.CallingApp.LMUY;
            app.RollingResistanceEditField.Value = app.CallingApp.k_R;
            app.BrakeForceNEditField.Value = app.CallingApp.FB;
            app.CamberEditField.Value = app.CallingApp.camber;
            app.wheelLoadRearEditField.Value = app.CallingApp.m_ph;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
            
            loadApp(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            saveData(app);
            
            % Disable Suspension Options button while dialog is open
            app.CallingApp.SuspensionButton.Enable = 'on';
            
            delete(app)            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            loadApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.WheelbasemmEditFieldLabel = uilabel(app.Panel);
            app.WheelbasemmEditFieldLabel.Text = 'Wheelbase [mm]';
            app.WheelbasemmEditField = uieditfield(app.Panel, 'numeric');
            app.TrackmmEditFieldLabel = uilabel(app.Panel);
            app.TrackmmEditFieldLabel.Text = 'Track [mm]';
            app.TrackmmEditField = uieditfield(app.Panel, 'numeric');
            app.CamberEditFieldLabel = uilabel(app.Panel);
            app.CamberEditFieldLabel.Text = 'Camber [°]';
            app.CamberEditField = uieditfield(app.Panel, 'numeric');
            app.wheelLoadRearEditFieldLabel = uilabel(app.Panel);
            app.wheelLoadRearEditFieldLabel.Text = 'wheel Load Rear [%]';
            app.wheelLoadRearEditField = uieditfield(app.Panel, 'numeric');
            app.TyreParametersPanel = uipanel(app.UIFigure);
            app.TyreParametersPanel.Title = 'Tyre Parameters';
            app.J_TirekgmEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.J_TirekgmEditFieldLabel.Text = 'J_Tire [kg*m²]';
            app.J_TirekgmEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.p_tirePaEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.p_tirePaEditFieldLabel.Text = 'p_tire [Pa]';
            app.p_tirePaEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.p_tirePaEditField.ValueDisplayFormat = '%.0f';
            app.LMUXSliderLabel = uilabel(app.TyreParametersPanel);
            app.LMUXSliderLabel.Text = 'LMUX';
            app.LMUXSlider = uislider(app.TyreParametersPanel);
            app.LMUXSlider.Limits = [0.6 0.75];
            app.LMUXSlider.MajorTicks = [0.6 0.65 0.7 0.75];
            app.LMUXSlider.Value = 0.65;
            app.LMUYSliderLabel = uilabel(app.TyreParametersPanel);
            app.LMUYSliderLabel.Text = 'LMUY';
            app.LMUYSlider = uislider(app.TyreParametersPanel);
            app.LMUYSlider.Limits = [0.6 0.75];
            app.LMUYSlider.MajorTicks = [0.6 0.65 0.7 0.75];
            app.LMUYSlider.Value = 0.65;
            app.RollingResistanceEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.RollingResistanceEditFieldLabel.Text = 'Rolling Resistance [-]';
            app.RollingResistanceEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.RollingResistanceEditField.ValueDisplayFormat = '%.3f';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BrakesPanel = uipanel(app.UIFigure);
            app.BrakesPanel.Title = 'Brakes';
            app.BrakeForceNEditFieldLabel = uilabel(app.BrakesPanel);
            app.BrakeForceNEditFieldLabel.Text = 'Brake Force [N]';
            app.BrakeForceNEditField = uieditfield(app.BrakesPanel, 'numeric');
            app.BrakeForceNEditField.ValueDisplayFormat = '%.0f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Suspension(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end